classdef Interfaz_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MENPRINCIPALTab                 matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        BUSCARButton                    matlab.ui.control.Button
        ALTABAJADEEQUIPOButton          matlab.ui.control.Button
        ORDENDESERVICIOButton           matlab.ui.control.Button
        HISTORIALDESERVICIOButton       matlab.ui.control.Button
        REPORTEDEINCIDENTESButton       matlab.ui.control.Button
        CALENDARIZACINButton            matlab.ui.control.Button
        GENERARCALENDARIOButton         matlab.ui.control.Button
        ALTAOBAJADEUSUARIOButton        matlab.ui.control.Button
        Image4                          matlab.ui.control.Image
        Image4_2                        matlab.ui.control.Image
        Image4_3                        matlab.ui.control.Image
        Image4_4                        matlab.ui.control.Image
        Image4_5                        matlab.ui.control.Image
        Image4_6                        matlab.ui.control.Image
        Image4_7                        matlab.ui.control.Image
        Image4_8                        matlab.ui.control.Image
        BUSCARTab                       matlab.ui.container.Tab
        Buscarpor                       matlab.ui.control.Label
        Buscarpormenu                   matlab.ui.control.DropDown
        Buscarporedit                   matlab.ui.control.EditField
        PanelTabla                      matlab.ui.container.Panel
        Inventario                      matlab.ui.control.Table
        Botonmas                        matlab.ui.control.Button
        BotonBorrar                     matlab.ui.control.Button
        Buscarpormenu_2                 matlab.ui.control.DropDown
        Buscarporedit_2                 matlab.ui.control.EditField
        Panel_4                         matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        AreaCheckBox                    matlab.ui.control.CheckBox
        ProveedordeCompraCheckBox       matlab.ui.control.CheckBox
        AccesoriosCheckBox              matlab.ui.control.CheckBox
        NumActivoFijoCheckBox           matlab.ui.control.CheckBox
        UbicacionCheckBox               matlab.ui.control.CheckBox
        FechadeInstalacinCheckBox       matlab.ui.control.CheckBox
        EstadodelEquipoCheckBox         matlab.ui.control.CheckBox
        ProveedorDeServicioCheckBox     matlab.ui.control.CheckBox
        ContactoCheckBox                matlab.ui.control.CheckBox
        TelefonoCheckBox                matlab.ui.control.CheckBox
        RefaccionesCambiadasCheckBox    matlab.ui.control.CheckBox
        ALTAOBAJADEEQTab                matlab.ui.container.Tab
        AltaoBajaDropDownLabel          matlab.ui.control.Label
        AltaoBajaDropDown               matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        text9_2                         matlab.ui.control.Label
        text9_3                         matlab.ui.control.Label
        text9_4                         matlab.ui.control.Label
        text9_5                         matlab.ui.control.Label
        text9_6                         matlab.ui.control.Label
        text9_7                         matlab.ui.control.Label
        text9_8                         matlab.ui.control.Label
        text9_9                         matlab.ui.control.Label
        text9_10                        matlab.ui.control.Label
        text9_11                        matlab.ui.control.Label
        text9_12                        matlab.ui.control.Label
        text9_13                        matlab.ui.control.Label
        FechaSolicitud_2                matlab.ui.control.EditField
        FechaSolicitud_3                matlab.ui.control.EditField
        FechaSolicitud_4                matlab.ui.control.EditField
        FechaSolicitud_5                matlab.ui.control.EditField
        FechaSolicitud_6                matlab.ui.control.EditField
        FechaSolicitud_7                matlab.ui.control.EditField
        FechaSolicitud_8                matlab.ui.control.EditField
        FechaSolicitud_9                matlab.ui.control.EditField
        FechaSolicitud_10               matlab.ui.control.EditField
        FechaSolicitud_11               matlab.ui.control.EditField
        FechaSolicitud_12               matlab.ui.control.EditField
        FechaSolicitud_13               matlab.ui.control.EditField
        GUARDARButton                   matlab.ui.control.Button
        ORDENDESERVICIOTab              matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        PanelOrden                      matlab.ui.container.Panel
        uipanel1                        matlab.ui.container.Panel
        text5                           matlab.ui.control.Label
        text6                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        Equipo                          matlab.ui.control.Label
        Equipo_2                        matlab.ui.control.Label
        Equipo_3                        matlab.ui.control.Label
        uipanel3                        matlab.ui.container.Panel
        text9                           matlab.ui.control.Label
        FechaSolicitud                  matlab.ui.control.EditField
        Folio                           matlab.ui.control.Label
        text21                          matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        FechaSolicitud_14               matlab.ui.control.EditField
        uipanel2                        matlab.ui.container.Panel
        text10                          matlab.ui.control.Label
        Depto                           matlab.ui.control.EditField
        Falla                           matlab.ui.control.EditField
        QuienReporta                    matlab.ui.control.EditField
        text15                          matlab.ui.control.Label
        text19                          matlab.ui.control.Label
        text23                          matlab.ui.control.Label
        uipanel4                        matlab.ui.container.Panel
        TrabajoHecho                    matlab.ui.control.EditField
        text12                          matlab.ui.control.Label
        text24                          matlab.ui.control.Label
        QuienRecibe                     matlab.ui.control.EditField
        text25                          matlab.ui.control.Label
        text26                          matlab.ui.control.Label
        Asignado                        matlab.ui.control.EditField
        uipanel5                        matlab.ui.container.Panel
        text13                          matlab.ui.control.Label
        EquipoDefectuosoCheckBox        matlab.ui.control.CheckBox
        MalaOperacinCheckBox            matlab.ui.control.CheckBox
        AsesoraTcnicaCheckBox           matlab.ui.control.CheckBox
        uipanel6                        matlab.ui.container.Panel
        text14                          matlab.ui.control.Label
        ManttoPreventivoCheckBox        matlab.ui.control.CheckBox
        ManttoCorrectivoCheckBox        matlab.ui.control.CheckBox
        AsesoraenServicioCheckBox       matlab.ui.control.CheckBox
        ServicioExternoCheckBox         matlab.ui.control.CheckBox
        uipanel7                        matlab.ui.container.Panel
        Refacciones                     matlab.ui.control.EditField
        tect30                          matlab.ui.control.Label
        uipanel10                       matlab.ui.container.Panel
        Observaciones                   matlab.ui.control.EditField
        text31                          matlab.ui.control.Label
        DatosGrales                     matlab.ui.control.Label
        uipanel2_2                      matlab.ui.container.Panel
        text10_2                        matlab.ui.control.Label
        text15_2                        matlab.ui.control.Label
        text15_3                        matlab.ui.control.Label
        text15_4                        matlab.ui.control.Label
        TotalHoras                      matlab.ui.control.EditField
        CostoRefacciones                matlab.ui.control.EditField
        CostoServExterno                matlab.ui.control.EditField
        text15_5                        matlab.ui.control.Label
        Total                           matlab.ui.control.EditField
        Horas                           matlab.ui.control.EditField
        uipanel2_3                      matlab.ui.container.Panel
        text10_3                        matlab.ui.control.Label
        RecibidoPor                     matlab.ui.control.EditField
        Comentarios                     matlab.ui.control.EditField
        FechaRecibo                     matlab.ui.control.EditField
        text15_6                        matlab.ui.control.Label
        text19_2                        matlab.ui.control.Label
        text23_2                        matlab.ui.control.Label
        IB                              matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        REPORTEDEINCIDENTESTab_2        matlab.ui.container.Tab
        PanelOrden_3                    matlab.ui.container.Panel
        uipanel1_3                      matlab.ui.container.Panel
        DatosGrales_3                   matlab.ui.control.Label
        uipanel4_3                      matlab.ui.container.Panel
        text12_6                        matlab.ui.control.Label
        text26_3                        matlab.ui.control.Label
        Asignado_6                      matlab.ui.control.EditField
        text26_4                        matlab.ui.control.Label
        Asignado_7                      matlab.ui.control.EditField
        text12_7                        matlab.ui.control.Label
        text12_8                        matlab.ui.control.Label
        text12_9                        matlab.ui.control.Label
        text15_18                       matlab.ui.control.Label
        text15_19                       matlab.ui.control.Label
        text15_20                       matlab.ui.control.Label
        text15_21                       matlab.ui.control.Label
        Asignado_8                      matlab.ui.control.EditField
        Asignado_9                      matlab.ui.control.EditField
        Asignado_10                     matlab.ui.control.EditField
        Asignado_11                     matlab.ui.control.EditField
        Asignado_12                     matlab.ui.control.EditField
        Asignado_13                     matlab.ui.control.EditField
        Asignado_14                     matlab.ui.control.EditField
        Asignado_15                     matlab.ui.control.EditField
        Asignado_16                     matlab.ui.control.EditField
        Asignado_17                     matlab.ui.control.EditField
        Asignado_18                     matlab.ui.control.EditField
        Asignado_19                     matlab.ui.control.EditField
        uipanel5_5                      matlab.ui.container.Panel
        SeguridadEquipoCheckBox_2       matlab.ui.control.CheckBox
        MalaOperacinCheckBox_3          matlab.ui.control.CheckBox
        DispositivosMdicoseHigieneCheckBox  matlab.ui.control.CheckBox
        uipanel2_7                      matlab.ui.container.Panel
        text10_7                        matlab.ui.control.Label
        CostoServExterno_3              matlab.ui.control.EditField
        IB_3                            matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        uipanel5_6                      matlab.ui.container.Panel
        ProteccinyVigilanciaCheckBox_2  matlab.ui.control.CheckBox
        EmergenciasCheckBox_2           matlab.ui.control.CheckBox
        MaterialesPeligrososCheckBox_2  matlab.ui.control.CheckBox
        SistemasclaveCheckBox_2         matlab.ui.control.CheckBox
        uipanel5_7                      matlab.ui.container.Panel
        ProcesosAdministrativosCheckBox_2  matlab.ui.control.CheckBox
        PacienteCheckBox_2              matlab.ui.control.CheckBox
        MdicoCheckBox_2                 matlab.ui.control.CheckBox
        OtrosCheckBox_2                 matlab.ui.control.CheckBox
        QuienReporta_4                  matlab.ui.control.EditField
        uipanel2_8                      matlab.ui.container.Panel
        text10_8                        matlab.ui.control.Label
        CostoServExterno_4              matlab.ui.control.EditField
        text12_3                        matlab.ui.control.Label
        Asignado_3                      matlab.ui.control.EditField
        text12_4                        matlab.ui.control.Label
        text12_5                        matlab.ui.control.Label
        Asignado_5                      matlab.ui.control.EditField
        uipanel2_9                      matlab.ui.container.Panel
        text10_9                        matlab.ui.control.Label
        CostoServExterno_5              matlab.ui.control.EditField
        text10_10                       matlab.ui.control.Label
        uipanel2_10                     matlab.ui.container.Panel
        text10_11                       matlab.ui.control.Label
        CostoServExterno_6              matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        TIPODEINCIDENTEButtonGroup      matlab.ui.container.ButtonGroup
        CasifallaButton                 matlab.ui.control.RadioButton
        EventoAdversoButton             matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
    end

    
    properties (Access = public)
        Ypanel=0; % Description
        tipoFiltro = "equipo"
        tablaFiltro = "Equipo"
        queryFiltrado = ""
        conn = []
        areas = []
        ubicaciones = []
        proveedoresServicio = []
        inventarioCompleto = [] 
        areaVisible = 'Area.nombre,';
        proveedorCompraVisible = 'Equipo.proveedor,';
        accesoriosVisible = 'Equipo.accesorios,';
        numActivoFijoVisible = 'Equipo.numActivoFijo,';
        ubicacionVisible = 'Ubicacion.nombre,';
        fechaInstalVisible = 'Equipo.fechaInstal,';
        estadoVisible = 'Equipo.estado,';
        consumiblesVisible = 'Equipo.consumibles,'
        proveedorServicioVisible = 'ProveedorServicio.nombre,';
        refaccionesVisible = 'Equipo.refaccionesCambiadas,'
        contactoVisible = 'ProveedorServicio.contacto,';
        telefonoVisible = 'ProveedorServicio.telefono';
        numActivoVisible = 'Equipo.numActivoFijo,';
        mainQuery = '';
        columnNamesArray = [];
    end
    
    methods (Access = private)
        function slider1_CreateFcn(~, hObject, ~, handles)
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Interfaz_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to Interfaz (see VARARGIN)
%             % Choose default command line output for Interfaz
%             app.output = hObject;
%             % Update handles structure
%             guidata(hObject, handles);
              app.PanelOrden.Scrollable = true;
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            disp(app.mainQuery)
            dbfile = fullfile(pwd,'Inventario.db');
            connection = sqlite(dbfile);
            inventario = fetch(connection,app.mainQuery);
            disp(inventario)
            %close(conn);
            app.conn = connection;
            app.tipoFiltro = 'equipo';
            app.Inventario.Data = inventario;
           
            app.inventarioCompleto = inventario;
            app.areas = fetch(app.conn,'SELECT Area.nombre FROM Area');
            app.ubicaciones = fetch(app.conn,'SELECT Ubicacion.nombre FROM Ubicacion');
            app.proveedoresServicio = fetch(app.conn,'SELECT * FROM ProveedorServicio');
            app.columnNamesArray = split(app.Inventario.ColumnName, ",");
            
            %[columnsR, columnsC] = size(app.columnNamesArray);
            
            %for x = 1 : columnsR
             % newMap = containers.Map({})
            %end 
            %disp(app.columnNamesArray)
            %{'Número de control', 'Equipo', 'Marca', 'Modelo', 'No. De serie', 'Área', 'Proveedor de compra', 'Accesorios', 'No. De activo fijo', 'Ubicación', 'Fecha de Instalación', 'Estado de equipo', 'Provedor de servicio', 'Refacciones cambiadas', 'Consumibles'};
            %close(conn);
        end

        % Button pushed function: Botonmas
        function BotonmasButtonPushed(app, event)
            app.Ypanel=-44;
            app.PanelTabla.Position=[18,18-44,1509,654];
            app.Buscarpormenu_2.Visible=true;
            app.Buscarporedit_2.Visible=true;
        end

        % Button pushed function: BotonBorrar
        function BotonBorrarButtonPushed(app, event)
            app.PanelTabla.Position=[18,18,1509,654];    
            app.Buscarpormenu_2.Visible=false;
            app.Buscarporedit_2.Visible=false;
        end

        % Callback function
        function BuscarporeditValueChanged(app, event)
            value = app.Buscarporedit.Value;
            disp(value)

        end

        % Value changed function: Buscarporedit
        function filtrar(app, event)
            value = app.Buscarporedit.Value;
            tabla = app.tablaFiltro;
            if strcmp(app.tablaFiltro,'TODA')
                buscar = fetch(app.conn,app.mainQuery);
            elseif strcmp(app.tablaFiltro,'numControl')
                buscar = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,Area.nombre,Ubicacion.nombre,Equipo.proveedor,Equipo.fechaInstal,Equipo.estado,Equipo.consumibles,Equipo.refaccionesCambiadas,ProveedorServicio.nombre,ProveedorServicio.contacto,ProveedorServicio.telefono FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id WHERE %s.%s = "%s";',tabla,app.tipoFiltro,value);
            else
                buscar = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,Area.nombre,Ubicacion.nombre,Equipo.proveedor,Equipo.fechaInstal,Equipo.estado,Equipo.consumibles,Equipo.refaccionesCambiadas,ProveedorServicio.nombre,ProveedorServicio.contacto,ProveedorServicio.telefono FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id WHERE %s.%s LIKE "%s%%";',tabla,app.tipoFiltro,value);
            end
            filtro = fetch(app.conn,buscar);
            app.Inventario.Data = filtro;
        end

        % Value changed function: Buscarpormenu
        function actualizarTipoFiltro(app, event)
            value = app.Buscarpormenu.Value;
            if strcmp(value,'Número de control')
               app.tipoFiltro = 'numControl';
               app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Equipo')
                app.tipoFiltro = 'equipo';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Marca')
                app.tipoFiltro = 'marca';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Modelo')
                app.tipoFiltro = 'modelo';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'No. De serie')
                app.tipoFiltro = 'numeroSerie';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Área')
                app.tipoFiltro = 'nombre';
                app.tablaFiltro = 'Area';
            elseif strcmp(value,'Proveedor de compra')
                app.tipoFiltro = 'proveedor';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Accesorios')
                app.tipoFiltro = 'accesorios';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Ubicación')
                app.tipoFiltro = 'nombre';
                app.tablaFiltro = 'Ubicacion';
            elseif strcmp(value,'Fecha de Instalación')
                app.tipoFiltro = 'fechaInstal';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Estado de equipo')
                app.tipoFiltro = 'estado';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Proveedor de servicio')
                app.tipoFiltro = 'proveedorServicio';
                app.tablaFiltro = 'ProveedorServicio';
            elseif strcmp(value,'Refacciones cambiadas')
                app.tipoFiltro = 'refaccionesCambiadas';
                app.tablaFiltro = 'Equipo';
            elseif strcmp(value,'Consumibles')
                app.tipoFiltro = 'consumibles';
            else
                app.tablaFiltro = 'TODA';
            end
        end

        % Value changed function: AreaCheckBox
        function esconderArea(app, event)
            value = app.AreaCheckBox.Value;
            if value == 0
               app.areaVisible = '';
               
            else
                app.areaVisible = 'Area.nombre,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
            
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: ProveedordeCompraCheckBox
        function esconderProveedorCompra(app, event)
            value = app.ProveedordeCompraCheckBox.Value;
            if value == 0
               app.proveedorCompraVisible = '';
               
            else
                app.proveedorCompraVisible = 'Equipo.proveedor,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: AccesoriosCheckBox
        function esconderAccesorios(app, event)
            value = app.AccesoriosCheckBox.Value;
            if value == 0
               app.accesoriosVisible = '';
               
            else
                app.accesoriosVisible = 'Equipo.accesorios,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: NumActivoFijoCheckBox
        function esconderActivoFijo(app, event)
            value = app.NumActivoFijoCheckBox.Value;
            if value == 0
               app.numActivoFijoVisible = '';
               
            else
                app.numActivoFijoVisible = 'Equipo.numActivoFijo,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: UbicacionCheckBox
        function esconderUbicacion(app, event)
            value = app.UbicacionCheckBox.Value;
            if value == 0
               app.ubicacionVisible = '';
               
            else
                app.ubicacionVisible = 'Ubicacion.nombre,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: FechadeInstalacinCheckBox
        function esconderFecha(app, event)
            value = app.FechadeInstalacinCheckBox.Value;
            if value == 0
               app.fechaInstalVisible = '';
               
            else
                app.fechaInstalVisible = 'Equipo.fechaInstal,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: EstadodelEquipoCheckBox
        function esconderEstado(app, event)
            value = app.EstadodelEquipoCheckBox.Value;
            if value == 0
               app.estadoVisible = '';
               
            else
                app.estadoVisible = 'Equipo.estado,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: ProveedorDeServicioCheckBox
        function esconderProveedorServicio(app, event)
            value = app.ProveedorDeServicioCheckBox.Value;
            if value == 0
               app.proveedorServicioVisible = '';
               
            else
                app.proveedorServicioVisible = 'ProveedorServicio.nombre,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: ContactoCheckBox
        function esconderContacto(app, event)
            value = app.ContactoCheckBox.Value;
            if value == 0
               app.contactoVisible = '';
               
            else
                app.contactoVisible = 'ProveedorServicio.contacto,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: TelefonoCheckBox
        function esconderTelefono(app, event)
            value = app.TelefonoCheckBox.Value;
            if value == 0
               app.telefonoVisible = '';
               
            else
                app.telefonoVisible = 'ProveedorServicio.telefono';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end

        % Value changed function: RefaccionesCambiadasCheckBox
        function esconderRefacciones(app, event)
            value = app.RefaccionesCambiadasCheckBox.Value;
            if value == 0
               app.refaccionesVisible = '';
               
            else
                app.refaccionesVisible = 'Equipo.refaccionesCambiadas,';
            end
            app.mainQuery = sprintf('SELECT Equipo.numControl,Equipo.equipo,Equipo.marca,Equipo.modelo,Equipo.numeroSerie,%s %s %s %s %s %s %s %s %s %s %s %s FROM Equipo JOIN Area ON Equipo.area = Area.id JOIN Ubicacion ON Equipo.ubicacion=Ubicacion.id JOIN ProveedorServicio ON Equipo.proveedorServicio = ProveedorServicio.id;', app.numActivoFijoVisible, app.areaVisible,app.ubicacionVisible,app.proveedorCompraVisible,app.fechaInstalVisible,app.estadoVisible,app.consumiblesVisible,app.accesoriosVisible ,app.refaccionesVisible,app.proveedorServicioVisible,app.contactoVisible,app.telefonoVisible);
           
            filtrar = fetch(app.conn,app.mainQuery);
            app.Inventario.Data = filtrar;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Interfaz';
            app.TabGroup = uitabgroup(app.figure1);
            app.MENPRINCIPALTab = uitab(app.TabGroup);
            app.MENPRINCIPALTab.Title = 'MENÚ PRINCIPAL';
            app.Image2 = uiimage(app.MENPRINCIPALTab);
            app.Image2.ImageSource = 'Logo.png';
            app.BUSCARButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.BUSCARButton.Text = 'BUSCAR';
            app.ALTABAJADEEQUIPOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.ALTABAJADEEQUIPOButton.Text = 'ALTA / BAJA DE EQUIPO';
            app.ORDENDESERVICIOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.ORDENDESERVICIOButton.Text = 'ORDEN DE SERVICIO';
            app.HISTORIALDESERVICIOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.HISTORIALDESERVICIOButton.Text = 'HISTORIAL DE SERVICIO';
            app.REPORTEDEINCIDENTESButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.REPORTEDEINCIDENTESButton.Text = 'REPORTE DE INCIDENTES';
            app.CALENDARIZACINButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.CALENDARIZACINButton.Text = 'CALENDARIZACIÓN';
            app.GENERARCALENDARIOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.GENERARCALENDARIOButton.Text = 'GENERAR CALENDARIO';
            app.ALTAOBAJADEUSUARIOButton = uibutton(app.MENPRINCIPALTab, 'push');
            app.ALTAOBAJADEUSUARIOButton.Text = 'ALTA O BAJA DE USUARIO';
            app.Image4 = uiimage(app.MENPRINCIPALTab);
            app.Image4.ImageSource = 'p9.png';
            app.Image4_2 = uiimage(app.MENPRINCIPALTab);
            app.Image4_2.ImageSource = 'Reporte.jpg.png';
            app.Image4_3 = uiimage(app.MENPRINCIPALTab);
            app.Image4_3.ImageSource = 'historial.png';
            app.Image4_4 = uiimage(app.MENPRINCIPALTab);
            app.Image4_4.ImageSource = 'p1.png';
            app.Image4_5 = uiimage(app.MENPRINCIPALTab);
            app.Image4_5.ImageSource = 'calendario.png';
            app.Image4_6 = uiimage(app.MENPRINCIPALTab);
            app.Image4_6.ImageSource = 'calendario.png';
            app.Image4_7 = uiimage(app.MENPRINCIPALTab);
            app.Image4_7.ImageSource = 'AltaBaja.png';
            app.Image4_8 = uiimage(app.MENPRINCIPALTab);
            app.Image4_8.ImageSource = 'LUPA1.jpg.png';
            app.BUSCARTab = uitab(app.TabGroup);
            app.BUSCARTab.Title = 'BUSCAR';
            app.Buscarpor = uilabel(app.BUSCARTab);
            app.Buscarpor.Text = 'Buscar por:';
            app.Buscarpormenu = uidropdown(app.BUSCARTab);
            app.Buscarpormenu.Items = {'Equipo', 'Número de control', 'Marca', 'Modelo', 'No. De serie', 'Área', 'Proveedor de compra', 'Accesorios', 'No. De activo fijo', 'Ubicación', 'Fecha de Instalación', 'Estado de equipo', 'Proveedor de servicio', 'Refacciones cambiadas', 'Consumibles'};
            app.Buscarpormenu.ValueChangedFcn = createCallbackFcn(app, @actualizarTipoFiltro, true);
            app.Buscarpormenu.Value = 'Equipo';
            app.Buscarporedit = uieditfield(app.BUSCARTab, 'text');
            app.Buscarporedit.ValueChangedFcn = createCallbackFcn(app, @filtrar, true);
            app.PanelTabla = uipanel(app.BUSCARTab);
            app.Inventario = uitable(app.PanelTabla);
            app.Inventario.ColumnName = {'Número de Control'; 'Equipo'; 'Marca'; 'Modelo'; 'Número de Serie'; 'Num Activo Fijo'; 'Área'; 'Ubicación'; 'Proveedor de Compra'; 'Fecha Instalación'; 'Estado'; 'Consumibles'; 'Accesorios'; 'Refacciones Cambiadas'; 'Proveedor de Servicio'; 'Contacto'; 'Teléfono'};
            app.Inventario.RowName = {''};
            app.Inventario.ColumnSortable = true;
            app.Botonmas = uibutton(app.BUSCARTab, 'push');
            app.Botonmas.ButtonPushedFcn = createCallbackFcn(app, @BotonmasButtonPushed, true);
            app.Botonmas.Text = '+';
            app.BotonBorrar = uibutton(app.BUSCARTab, 'push');
            app.BotonBorrar.ButtonPushedFcn = createCallbackFcn(app, @BotonBorrarButtonPushed, true);
            app.BotonBorrar.Text = 'Borrar historial';
            app.Buscarpormenu_2 = uidropdown(app.BUSCARTab);
            app.Buscarpormenu_2.Items = {'Número de control', 'Equipo', 'Marca', 'Modelo', 'No. De serie', 'Área', 'Proveedor de compra', 'Accesorios', 'No. De activo fijo', 'Ubicación', 'Fecha de Instalación', 'Estado de equipo', 'Provedor de servicio', 'Refacciones cambiadas', 'Consumibles'};
            app.Buscarpormenu_2.Visible = 'off';
            app.Buscarpormenu_2.Value = 'Número de control';
            app.Buscarporedit_2 = uieditfield(app.BUSCARTab, 'text');
            app.Buscarporedit_2.Visible = 'off';
            app.Panel_4 = uipanel(app.BUSCARTab);
            app.Label_2 = uilabel(app.Panel_4);
            app.Label_2.Text = '';
            app.AreaCheckBox = uicheckbox(app.Panel_4);
            app.AreaCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderArea, true);
            app.AreaCheckBox.Text = 'Area';
            app.AreaCheckBox.Value = true;
            app.ProveedordeCompraCheckBox = uicheckbox(app.Panel_4);
            app.ProveedordeCompraCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderProveedorCompra, true);
            app.ProveedordeCompraCheckBox.Text = 'Proveedor de Compra';
            app.ProveedordeCompraCheckBox.Value = true;
            app.AccesoriosCheckBox = uicheckbox(app.Panel_4);
            app.AccesoriosCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderAccesorios, true);
            app.AccesoriosCheckBox.Text = 'Accesorios';
            app.AccesoriosCheckBox.Value = true;
            app.NumActivoFijoCheckBox = uicheckbox(app.Panel_4);
            app.NumActivoFijoCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderActivoFijo, true);
            app.NumActivoFijoCheckBox.Text = 'Num. Activo Fijo';
            app.NumActivoFijoCheckBox.Value = true;
            app.UbicacionCheckBox = uicheckbox(app.Panel_4);
            app.UbicacionCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderUbicacion, true);
            app.UbicacionCheckBox.Text = 'Ubicación';
            app.UbicacionCheckBox.Value = true;
            app.FechadeInstalacinCheckBox = uicheckbox(app.Panel_4);
            app.FechadeInstalacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderFecha, true);
            app.FechadeInstalacinCheckBox.Text = 'Fecha de Instalación';
            app.FechadeInstalacinCheckBox.Value = true;
            app.EstadodelEquipoCheckBox = uicheckbox(app.Panel_4);
            app.EstadodelEquipoCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderEstado, true);
            app.EstadodelEquipoCheckBox.Text = 'Estado del Equipo';
            app.EstadodelEquipoCheckBox.Value = true;
            app.ProveedorDeServicioCheckBox = uicheckbox(app.Panel_4);
            app.ProveedorDeServicioCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderProveedorServicio, true);
            app.ProveedorDeServicioCheckBox.Text = 'Proveedor De Servicio';
            app.ProveedorDeServicioCheckBox.Value = true;
            app.ContactoCheckBox = uicheckbox(app.Panel_4);
            app.ContactoCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderContacto, true);
            app.ContactoCheckBox.Text = 'Contacto';
            app.ContactoCheckBox.Value = true;
            app.TelefonoCheckBox = uicheckbox(app.Panel_4);
            app.TelefonoCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderTelefono, true);
            app.TelefonoCheckBox.Text = 'Teléfono';
            app.TelefonoCheckBox.Value = true;
            app.RefaccionesCambiadasCheckBox = uicheckbox(app.Panel_4);
            app.RefaccionesCambiadasCheckBox.ValueChangedFcn = createCallbackFcn(app, @esconderRefacciones, true);
            app.RefaccionesCambiadasCheckBox.Text = 'Refacciones Cambiadas';
            app.RefaccionesCambiadasCheckBox.Value = true;
            app.ALTAOBAJADEEQTab = uitab(app.TabGroup);
            app.ALTAOBAJADEEQTab.Title = 'ALTA O BAJA DE EQ';
            app.AltaoBajaDropDownLabel = uilabel(app.ALTAOBAJADEEQTab);
            app.AltaoBajaDropDownLabel.Text = 'Alta o Baja';
            app.AltaoBajaDropDown = uidropdown(app.ALTAOBAJADEEQTab);
            app.AltaoBajaDropDown.Items = {'Alta', 'Baja'};
            app.AltaoBajaDropDown.Value = 'Alta';
            app.Panel_3 = uipanel(app.ALTAOBAJADEEQTab);
            app.Panel_3.Title = 'Panel';
            app.text9_2 = uilabel(app.Panel_3);
            app.text9_2.Text = 'EQUIPO';
            app.text9_3 = uilabel(app.Panel_3);
            app.text9_3.Text = 'MARCA';
            app.text9_4 = uilabel(app.Panel_3);
            app.text9_4.Text = 'MODELO';
            app.text9_5 = uilabel(app.Panel_3);
            app.text9_5.Text = 'No. DE SERIE';
            app.text9_6 = uilabel(app.Panel_3);
            app.text9_6.Text = 'AREA';
            app.text9_7 = uilabel(app.Panel_3);
            app.text9_7.Text = 'PROVEEDOR DE COMPRA';
            app.text9_8 = uilabel(app.Panel_3);
            app.text9_8.Text = 'ACCESORIOS';
            app.text9_9 = uilabel(app.Panel_3);
            app.text9_9.Text = 'NÚMEROD DE ACTIVO FIJO';
            app.text9_10 = uilabel(app.Panel_3);
            app.text9_10.Text = 'UBICACIÓN';
            app.text9_11 = uilabel(app.Panel_3);
            app.text9_11.Text = 'FECHA DE INSTALACIÓN';
            app.text9_12 = uilabel(app.Panel_3);
            app.text9_12.Text = 'ESTADO DEL EQUIPO';
            app.text9_13 = uilabel(app.Panel_3);
            app.text9_13.Text = 'PROVEEDOR DE SERVICIOS';
            app.FechaSolicitud_2 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_3 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_4 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_5 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_6 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_7 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_8 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_9 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_10 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_11 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_12 = uieditfield(app.Panel_3, 'text');
            app.FechaSolicitud_13 = uieditfield(app.Panel_3, 'text');
            app.GUARDARButton = uibutton(app.ALTAOBAJADEEQTab, 'push');
            app.GUARDARButton.Text = 'GUARDAR';
            app.ORDENDESERVICIOTab = uitab(app.TabGroup);
            app.ORDENDESERVICIOTab.Title = 'ORDEN DE SERVICIO';
            app.Panel = uipanel(app.ORDENDESERVICIOTab);
            app.PanelOrden = uipanel(app.Panel);
            app.uipanel1 = uipanel(app.PanelOrden);
            app.text5 = uilabel(app.uipanel1);
            app.text5.Text = 'No. DE CONTROL';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'No. DE SERIE';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'EQUIPO';
            app.Equipo = uilabel(app.uipanel1);
            app.Equipo.Text = '';
            app.Equipo_2 = uilabel(app.uipanel1);
            app.Equipo_2.Text = '';
            app.Equipo_3 = uilabel(app.uipanel1);
            app.Equipo_3.Text = '';
            app.uipanel3 = uipanel(app.PanelOrden);
            app.text9 = uilabel(app.uipanel3);
            app.text9.Text = 'SOLICITUD DE SERVICIO FOLIO';
            app.FechaSolicitud = uieditfield(app.uipanel3, 'text');
            app.Folio = uilabel(app.uipanel3);
            app.Folio.Text = '';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Text = 'FECHA DE SOLICITUD';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Text = 'FECHA DE TERMINACIÓN';
            app.FechaSolicitud_14 = uieditfield(app.uipanel3, 'text');
            app.uipanel2 = uipanel(app.PanelOrden);
            app.text10 = uilabel(app.uipanel2);
            app.text10.Text = 'DEPARTAMENTO SOLICITANTE';
            app.Depto = uieditfield(app.uipanel2, 'text');
            app.Falla = uieditfield(app.uipanel2, 'text');
            app.QuienReporta = uieditfield(app.uipanel2, 'text');
            app.text15 = uilabel(app.uipanel2);
            app.text15.Text = 'DEPARTAMENTO';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Text = 'FALLA REPORTADA';
            app.text23 = uilabel(app.uipanel2);
            app.text23.Text = 'REPORTA';
            app.uipanel4 = uipanel(app.PanelOrden);
            app.TrabajoHecho = uieditfield(app.uipanel4, 'text');
            app.text12 = uilabel(app.uipanel4);
            app.text12.Text = 'INGENIERIA BIOMEDICA';
            app.text24 = uilabel(app.uipanel4);
            app.text24.Text = 'RECIBE:';
            app.QuienRecibe = uieditfield(app.uipanel4, 'text');
            app.text25 = uilabel(app.uipanel4);
            app.text25.Text = 'TRABAJO REALIZADO';
            app.text26 = uilabel(app.uipanel4);
            app.text26.Text = 'ASIGNADO A:';
            app.Asignado = uieditfield(app.uipanel4, 'text');
            app.uipanel5 = uipanel(app.PanelOrden);
            app.text13 = uilabel(app.uipanel5);
            app.text13.Text = 'FALLA ENCONTRADA';
            app.EquipoDefectuosoCheckBox = uicheckbox(app.uipanel5);
            app.EquipoDefectuosoCheckBox.Text = 'Equipo Defectuoso';
            app.MalaOperacinCheckBox = uicheckbox(app.uipanel5);
            app.MalaOperacinCheckBox.Text = 'Mala Operación';
            app.AsesoraTcnicaCheckBox = uicheckbox(app.uipanel5);
            app.AsesoraTcnicaCheckBox.Text = 'Asesoría Técnica';
            app.uipanel6 = uipanel(app.PanelOrden);
            app.text14 = uilabel(app.uipanel6);
            app.text14.Text = 'TRABAJO REALIZADO';
            app.ManttoPreventivoCheckBox = uicheckbox(app.uipanel6);
            app.ManttoPreventivoCheckBox.Text = 'Mantto. Preventivo';
            app.ManttoCorrectivoCheckBox = uicheckbox(app.uipanel6);
            app.ManttoCorrectivoCheckBox.Text = 'Mantto. Correctivo';
            app.AsesoraenServicioCheckBox = uicheckbox(app.uipanel6);
            app.AsesoraenServicioCheckBox.Text = 'Asesoría en Servicio';
            app.ServicioExternoCheckBox = uicheckbox(app.uipanel6);
            app.ServicioExternoCheckBox.Text = 'Servicio Externo';
            app.uipanel7 = uipanel(app.PanelOrden);
            app.Refacciones = uieditfield(app.uipanel7, 'text');
            app.tect30 = uilabel(app.uipanel7);
            app.tect30.Text = 'PARTES NUEVAS';
            app.uipanel10 = uipanel(app.PanelOrden);
            app.Observaciones = uieditfield(app.uipanel10, 'text');
            app.text31 = uilabel(app.uipanel10);
            app.text31.Text = 'OBSERVACIONES:';
            app.DatosGrales = uilabel(app.PanelOrden);
            app.DatosGrales.Text = 'DATOS GENERALES';
            app.uipanel2_2 = uipanel(app.PanelOrden);
            app.text10_2 = uilabel(app.uipanel2_2);
            app.text10_2.Text = 'COSTOS ESTIMADOS';
            app.text15_2 = uilabel(app.uipanel2_2);
            app.text15_2.Text = 'HORAS/INGENIERO';
            app.text15_3 = uilabel(app.uipanel2_2);
            app.text15_3.Text = 'COSTO REFACCIONES';
            app.text15_4 = uilabel(app.uipanel2_2);
            app.text15_4.Text = 'COSTO SERVICIO EXTERNO';
            app.TotalHoras = uieditfield(app.uipanel2_2, 'text');
            app.CostoRefacciones = uieditfield(app.uipanel2_2, 'text');
            app.CostoServExterno = uieditfield(app.uipanel2_2, 'text');
            app.text15_5 = uilabel(app.uipanel2_2);
            app.text15_5.Text = 'TOTAL';
            app.Total = uieditfield(app.uipanel2_2, 'text');
            app.Horas = uieditfield(app.uipanel2_2, 'text');
            app.uipanel2_3 = uipanel(app.PanelOrden);
            app.text10_3 = uilabel(app.uipanel2_3);
            app.text10_3.Text = 'DEPARTAMENTO SOLICITANTE';
            app.RecibidoPor = uieditfield(app.uipanel2_3, 'text');
            app.Comentarios = uieditfield(app.uipanel2_3, 'text');
            app.FechaRecibo = uieditfield(app.uipanel2_3, 'text');
            app.text15_6 = uilabel(app.uipanel2_3);
            app.text15_6.Text = 'RECIBIDO DE CONFORMIDAD POR:';
            app.text19_2 = uilabel(app.uipanel2_3);
            app.text19_2.Text = 'COMENTARIOS:';
            app.text23_2 = uilabel(app.uipanel2_3);
            app.text23_2.Text = 'FECHA:';
            app.IB = uilabel(app.PanelOrden);
            app.IB.Text = 'INGENIERÍA BIOMEDICA';
            app.Image = uiimage(app.PanelOrden);
            app.Image.ImageSource = 'Hospital Angeles.jpg';
            app.REPORTEDEINCIDENTESTab_2 = uitab(app.TabGroup);
            app.REPORTEDEINCIDENTESTab_2.Title = 'REPORTE DE INCIDENTES';
            app.PanelOrden_3 = uipanel(app.REPORTEDEINCIDENTESTab_2);
            app.uipanel1_3 = uipanel(app.PanelOrden_3);
            app.DatosGrales_3 = uilabel(app.uipanel1_3);
            app.DatosGrales_3.Text = 'CLASIFICACIÓN DE INCIDENTES';
            app.uipanel4_3 = uipanel(app.PanelOrden_3);
            app.text12_6 = uilabel(app.uipanel4_3);
            app.text12_6.Text = 'PERSONAL INVOLUCRADO';
            app.text26_3 = uilabel(app.uipanel4_3);
            app.text26_3.Text = 'Servicio Involucrado';
            app.Asignado_6 = uieditfield(app.uipanel4_3, 'text');
            app.text26_4 = uilabel(app.uipanel4_3);
            app.text26_4.Text = 'Fecha Incidente';
            app.Asignado_7 = uieditfield(app.uipanel4_3, 'text');
            app.text12_7 = uilabel(app.uipanel4_3);
            app.text12_7.Text = 'NOMBRE';
            app.text12_8 = uilabel(app.uipanel4_3);
            app.text12_8.Text = 'PUESTO';
            app.text12_9 = uilabel(app.uipanel4_3);
            app.text12_9.Text = 'ANTIGÜEDAD';
            app.text15_18 = uilabel(app.uipanel4_3);
            app.text15_18.Text = '1.';
            app.text15_19 = uilabel(app.uipanel4_3);
            app.text15_19.Text = '2.';
            app.text15_20 = uilabel(app.uipanel4_3);
            app.text15_20.Text = '3.';
            app.text15_21 = uilabel(app.uipanel4_3);
            app.text15_21.Text = '4.';
            app.Asignado_8 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_9 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_10 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_11 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_12 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_13 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_14 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_15 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_16 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_17 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_18 = uieditfield(app.uipanel4_3, 'text');
            app.Asignado_19 = uieditfield(app.uipanel4_3, 'text');
            app.uipanel5_5 = uipanel(app.PanelOrden_3);
            app.SeguridadEquipoCheckBox_2 = uicheckbox(app.uipanel5_5);
            app.SeguridadEquipoCheckBox_2.Text = 'Seguridad Equipo';
            app.MalaOperacinCheckBox_3 = uicheckbox(app.uipanel5_5);
            app.MalaOperacinCheckBox_3.Text = 'Mala Operación';
            app.DispositivosMdicoseHigieneCheckBox = uicheckbox(app.uipanel5_5);
            app.DispositivosMdicoseHigieneCheckBox.Text = 'Dispositivos Médicos e Higiene';
            app.uipanel2_7 = uipanel(app.PanelOrden_3);
            app.text10_7 = uilabel(app.uipanel2_7);
            app.text10_7.Text = 'SITUACIÓN';
            app.CostoServExterno_3 = uieditfield(app.uipanel2_7, 'text');
            app.IB_3 = uilabel(app.PanelOrden_3);
            app.IB_3.Text = 'REGISTRO DE INCIDENTES';
            app.Image_3 = uiimage(app.PanelOrden_3);
            app.Image_3.ImageSource = 'Hospital Angeles.jpg';
            app.uipanel5_6 = uipanel(app.PanelOrden_3);
            app.ProteccinyVigilanciaCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.ProteccinyVigilanciaCheckBox_2.Text = 'Protección y Vigilancia';
            app.EmergenciasCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.EmergenciasCheckBox_2.Text = 'Emergencias';
            app.MaterialesPeligrososCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.MaterialesPeligrososCheckBox_2.Text = 'Materiales Peligrosos';
            app.SistemasclaveCheckBox_2 = uicheckbox(app.uipanel5_6);
            app.SistemasclaveCheckBox_2.Text = 'Sistemas clave';
            app.uipanel5_7 = uipanel(app.PanelOrden_3);
            app.ProcesosAdministrativosCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.ProcesosAdministrativosCheckBox_2.Text = 'Procesos Administrativos';
            app.PacienteCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.PacienteCheckBox_2.Text = 'Paciente';
            app.MdicoCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.MdicoCheckBox_2.Text = 'Médico';
            app.OtrosCheckBox_2 = uicheckbox(app.uipanel5_7);
            app.OtrosCheckBox_2.Text = 'Otros';
            app.QuienReporta_4 = uieditfield(app.uipanel5_7, 'text');
            app.uipanel2_8 = uipanel(app.PanelOrden_3);
            app.text10_8 = uilabel(app.uipanel2_8);
            app.text10_8.Text = 'BACKGROUND';
            app.CostoServExterno_4 = uieditfield(app.uipanel2_8, 'text');
            app.text12_3 = uilabel(app.PanelOrden_3);
            app.text12_3.Text = 'ELABORO:';
            app.Asignado_3 = uieditfield(app.PanelOrden_3, 'text');
            app.text12_4 = uilabel(app.PanelOrden_3);
            app.text12_4.Text = 'FIRMA:';
            app.text12_5 = uilabel(app.PanelOrden_3);
            app.text12_5.Text = 'FECHA Y HORA';
            app.Asignado_5 = uieditfield(app.PanelOrden_3, 'text');
            app.uipanel2_9 = uipanel(app.PanelOrden_3);
            app.text10_9 = uilabel(app.uipanel2_9);
            app.text10_9.Text = 'ACCIONES';
            app.CostoServExterno_5 = uieditfield(app.uipanel2_9, 'text');
            app.text10_10 = uilabel(app.uipanel2_9);
            app.text10_10.Text = 'INMEDIATAS';
            app.uipanel2_10 = uipanel(app.PanelOrden_3);
            app.text10_11 = uilabel(app.uipanel2_10);
            app.text10_11.Text = 'RECOMENDACIONES';
            app.CostoServExterno_6 = uieditfield(app.uipanel2_10, 'text');
            app.Label = uilabel(app.PanelOrden_3);
            app.Label.Text = '__________________';
            app.TIPODEINCIDENTEButtonGroup = uibuttongroup(app.PanelOrden_3);
            app.TIPODEINCIDENTEButtonGroup.Title = 'TIPO DE INCIDENTE';
            app.CasifallaButton = uiradiobutton(app.TIPODEINCIDENTEButtonGroup);
            app.CasifallaButton.Text = {'Casi falla: Desviaciòn en el proceso organizacional que se indica antes de que generen un impacto'; 'en la estructura personas o en la operaciòn del hospital'};
            app.CasifallaButton.Value = true;
            app.EventoAdversoButton = uiradiobutton(app.TIPODEINCIDENTEButtonGroup);
            app.EventoAdversoButton.Text = {'Evento Adverso: Desviaciòn en el proceso organizacional que tiene bajo impacto en la estructura'; ' personas o en la operaciòn del hospital'};
            app.Button3 = uiradiobutton(app.TIPODEINCIDENTEButtonGroup);
            app.Button3.Text = {'Evento Adverso: Desviaciòn en el proceso organizacional que tiene alto impacto en la estructura'; ' personas o en la operaciòn del hospital'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Interfaz_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end